classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FISSelectionUIFigure           matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NiveldeSeleccinTab             matlab.ui.container.Tab
        UIAxesSeleccion                matlab.ui.control.UIAxes
        RequisitosTab                  matlab.ui.container.Tab
        UIAxesRequisitos               matlab.ui.control.UIAxes
        ConocimientosTab               matlab.ui.container.Tab
        UIAxesConocimientos            matlab.ui.control.UIAxes
        ExperienciaTab                 matlab.ui.container.Tab
        UIAxesExperiencia              matlab.ui.control.UIAxes
        SegundaLenguaTab               matlab.ui.container.Tab
        UIAxesSegundaLengua            matlab.ui.control.UIAxes
        ProduccinTab                   matlab.ui.container.Tab
        UIAxesProduccion               matlab.ui.control.UIAxes
        RequisitosSliderLabel          matlab.ui.control.Label
        RequisitosSlider               matlab.ui.control.Slider
        ConocimientosSliderLabel       matlab.ui.control.Label
        ConocimientosSlider            matlab.ui.control.Slider
        ExperienciaSliderLabel         matlab.ui.control.Label
        ExperienciaSlider              matlab.ui.control.Slider
        SegundaLenguaSliderLabel       matlab.ui.control.Label
        SegundaLenguaSlider            matlab.ui.control.Slider
        ProduccinSliderLabel           matlab.ui.control.Label
        ProduccionSlider               matlab.ui.control.Slider
        NiveldeSeleccinEditFieldLabel  matlab.ui.control.Label
        NPSeleccionEditField           matlab.ui.control.NumericEditField
        PocoaptoGaugeLabel             matlab.ui.control.Label
        PocoaptoGauge                  matlab.ui.control.SemicircularGauge
        AptoGaugeLabel                 matlab.ui.control.Label
        AptoGauge                      matlab.ui.control.SemicircularGauge
        NoaptoGaugeLabel               matlab.ui.control.Label
        NoaptoGauge                    matlab.ui.control.SemicircularGauge
        PocoaptoEditField              matlab.ui.control.NumericEditField
        AptoEditField                  matlab.ui.control.NumericEditField
        NoaptoEditField                matlab.ui.control.NumericEditField
        GradosdePertenenciaLabel       matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        fisSeleccion = readfis('Seleccion');
        mf = [fismf("trapmf",[0 0 3 4],'Name',"No apto");
                  fismf("trimf",[3 5 7],'Name',"Poco apto");
                  fismf("trapmf",[6 7 10 10],'Name',"Apto")];
    end
    
    methods (Access = private)
        
        function plotRequisitos(app)
            [xOut,yOut] = plotmf(app.fisSeleccion,'input',1);
            plot(app.UIAxesRequisitos,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotConocimientos(app)
            [xOut,yOut] = plotmf(app.fisSeleccion,'input',2);
            plot(app.UIAxesConocimientos,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotExperiencia(app)
            [xOut,yOut] = plotmf(app.fisSeleccion,'input',3);
            plot(app.UIAxesExperiencia,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotSegundaLengua(app)
            [xOut,yOut] = plotmf(app.fisSeleccion,'input',4);
            plot(app.UIAxesSegundaLengua,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotProduccion(app)
            [xOut,yOut] = plotmf(app.fisSeleccion,'input',5);
            plot(app.UIAxesProduccion,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotSeleccion(app)
            [xOut,yOut] = plotmf(app.fisSeleccion,'output',1);
            plot(app.UIAxesSeleccion,xOut,yOut,'DisplayName','yOut')
        end
        
        function plotPertenenciaRequisitos(app, value)
            dibujarLineaPertenencia(app, app.UIAxesRequisitos, value)
            legend(app.UIAxesRequisitos, 'Técnico', 'Universitario', 'Especialización', 'Maestría', 'Doctorado', 'Grados de pertenencia')
        end
        
        function plotPertenenciaConocimientos(app, value)
            dibujarLineaPertenencia(app, app.UIAxesConocimientos, value)
            legend(app.UIAxesConocimientos, 'Muy confiable', 'Confiable', 'No Confiable', 'Grados de pertenencia')
        end
        
         function plotPertenenciaExperiencia(app, value)
            dibujarLineaPertenencia(app, app.UIAxesExperiencia, value)
            legend(app.UIAxesExperiencia, 'Muy poca', 'Poca', 'Suficiente', 'Mucha',  'Grados de pertenencia')
        end
        
        function plotPertenenciaSegundaLengua(app, value)
            dibujarLineaPertenencia(app, app.UIAxesSegundaLengua, value)
            legend(app.UIAxesSegundaLengua, 'Básico', 'Independiente', 'Proeficiente', 'Grados de pertenencia')
        end
        
        function plotPertenenciaProduccion(app, value)
            dibujarLineaPertenencia(app, app.UIAxesProduccion, value)
            legend(app.UIAxesProduccion, 'Baja', 'Media', 'Alta', 'Grados de pertenencia')
        end
                
        function plotPertenenciaSeleccion(app, value)
            dibujarLineaPertenencia(app, app.UIAxesSeleccion, value)
            legend(app.UIAxesSeleccion, 'No Apto', 'Poco Apto', 'Apto', 'Grado de pertenencia')
            
        end
        
        function dibujarLineaPertenencia(~, axes, value)
            line = findobj(axes, 'Label', '*');
            if ~isempty(line)
                delete(line);
            end  
        
            xline(axes, value, '--r', '*');
        end
        
        function calcularPertenencia(app)
            d = uiprogressdlg(app.FISSelectionUIFigure,'Title','Calculando nivel de selección...',...
                'Indeterminate','on');
            
            seleccion = evalfis(app.fisSeleccion, [app.RequisitosSlider.Value, app.ConocimientosSlider.Value, app.ExperienciaSlider.Value, app.SegundaLenguaSlider.Value, app.ProduccionSlider.Value]);
            
            app.NPSeleccionEditField.Value = seleccion;
            
            y = evalmf(app.mf, seleccion);
            
            app.AptoGauge.Value = y(3);
            app.PocoaptoGauge.Value = y(2);
            app.NoaptoGauge.Value = y(1);
            
            app.AptoEditField.Value = y(3);
            app.PocoaptoEditField.Value = y(2);
            app.NoaptoEditField.Value = y(1);       
            
            plotPertenenciaSeleccion(app, app.NPSeleccionEditField.Value);
            plotPertenenciaRequisitos(app, app.RequisitosSlider.Value);
            plotPertenenciaConocimientos(app, app.ConocimientosSlider.Value);
            plotPertenenciaExperiencia(app, app.ExperienciaSlider.Value);
            plotPertenenciaSegundaLengua(app, app.SegundaLenguaSlider.Value);
            plotPertenenciaProduccion(app, app.ProduccionSlider.Value);
            
            pause(0)
            
            close(d);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plotSeleccion(app)
            plotRequisitos(app)
            plotConocimientos(app)
            plotExperiencia(app)
            plotSegundaLengua(app)
            plotProduccion(app)
            
            calcularPertenencia(app)
        end

        % Value changed function: ConocimientosSlider, 
        % ExperienciaSlider, ProduccionSlider, RequisitosSlider, 
        % SegundaLenguaSlider
        function CalcularButtonPushed(app, event)
           calcularPertenencia(app)
        end

        % Callback function
        function NivelEscolarEditFieldValueChanged(app, event)
            calcularPertenencia(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FISSelectionUIFigure = uifigure('Visible', 'off');
            app.FISSelectionUIFigure.Name = 'FIS-Selection';
            app.TabGroup = uitabgroup(app.FISSelectionUIFigure);
            app.NiveldeSeleccinTab = uitab(app.TabGroup);
            app.NiveldeSeleccinTab.Title = 'Nivel de Selección';
            app.UIAxesSeleccion = uiaxes(app.NiveldeSeleccinTab);
            title(app.UIAxesSeleccion, 'Nivel de Selección')
            xlabel(app.UIAxesSeleccion, 'X')
            ylabel(app.UIAxesSeleccion, 'Pertenencia')
            app.RequisitosTab = uitab(app.TabGroup);
            app.RequisitosTab.Title = 'Requisitos';
            app.UIAxesRequisitos = uiaxes(app.RequisitosTab);
            title(app.UIAxesRequisitos, 'Requisitos')
            xlabel(app.UIAxesRequisitos, 'X')
            ylabel(app.UIAxesRequisitos, 'Pertenencia')
            app.ConocimientosTab = uitab(app.TabGroup);
            app.ConocimientosTab.Title = 'Conocimientos';
            app.UIAxesConocimientos = uiaxes(app.ConocimientosTab);
            title(app.UIAxesConocimientos, 'Conocimientos')
            xlabel(app.UIAxesConocimientos, 'X')
            ylabel(app.UIAxesConocimientos, 'Pertenencia')
            app.ExperienciaTab = uitab(app.TabGroup);
            app.ExperienciaTab.Title = 'Experiencia';
            app.UIAxesExperiencia = uiaxes(app.ExperienciaTab);
            title(app.UIAxesExperiencia, 'Experiencia')
            xlabel(app.UIAxesExperiencia, 'X')
            ylabel(app.UIAxesExperiencia, 'Pertenencia')
            app.SegundaLenguaTab = uitab(app.TabGroup);
            app.SegundaLenguaTab.Title = 'Segunda Lengua';
            app.UIAxesSegundaLengua = uiaxes(app.SegundaLenguaTab);
            title(app.UIAxesSegundaLengua, 'Segunda Lengua')
            xlabel(app.UIAxesSegundaLengua, 'X')
            ylabel(app.UIAxesSegundaLengua, 'Pertenencia')
            app.ProduccinTab = uitab(app.TabGroup);
            app.ProduccinTab.Title = 'Producción';
            app.UIAxesProduccion = uiaxes(app.ProduccinTab);
            title(app.UIAxesProduccion, 'Producción')
            xlabel(app.UIAxesProduccion, 'X')
            ylabel(app.UIAxesProduccion, 'Pertenencia')
            app.RequisitosSliderLabel = uilabel(app.FISSelectionUIFigure);
            app.RequisitosSliderLabel.Text = 'Requisitos';
            app.RequisitosSlider = uislider(app.FISSelectionUIFigure);
            app.RequisitosSlider.Limits = [0 10];
            app.RequisitosSlider.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.RequisitosSlider.Value = 5;
            app.ConocimientosSliderLabel = uilabel(app.FISSelectionUIFigure);
            app.ConocimientosSliderLabel.Text = 'Conocimientos';
            app.ConocimientosSlider = uislider(app.FISSelectionUIFigure);
            app.ConocimientosSlider.Limits = [0 10];
            app.ConocimientosSlider.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.ConocimientosSlider.Value = 5;
            app.ExperienciaSliderLabel = uilabel(app.FISSelectionUIFigure);
            app.ExperienciaSliderLabel.Text = 'Experiencia';
            app.ExperienciaSlider = uislider(app.FISSelectionUIFigure);
            app.ExperienciaSlider.Limits = [0 10];
            app.ExperienciaSlider.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.ExperienciaSlider.Value = 5;
            app.SegundaLenguaSliderLabel = uilabel(app.FISSelectionUIFigure);
            app.SegundaLenguaSliderLabel.Text = 'Segunda Lengua';
            app.SegundaLenguaSlider = uislider(app.FISSelectionUIFigure);
            app.SegundaLenguaSlider.Limits = [80 220];
            app.SegundaLenguaSlider.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.SegundaLenguaSlider.Value = 150;
            app.ProduccinSliderLabel = uilabel(app.FISSelectionUIFigure);
            app.ProduccinSliderLabel.Text = 'Producción';
            app.ProduccionSlider = uislider(app.FISSelectionUIFigure);
            app.ProduccionSlider.Limits = [0 10];
            app.ProduccionSlider.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.ProduccionSlider.Value = 5;
            app.NiveldeSeleccinEditFieldLabel = uilabel(app.FISSelectionUIFigure);
            app.NiveldeSeleccinEditFieldLabel.Text = 'Nivel de Selección';
            app.NPSeleccionEditField = uieditfield(app.FISSelectionUIFigure, 'numeric');
            app.NPSeleccionEditField.Editable = 'off';
            app.PocoaptoGaugeLabel = uilabel(app.FISSelectionUIFigure);
            app.PocoaptoGaugeLabel.Text = 'Poco apto';
            app.PocoaptoGauge = uigauge(app.FISSelectionUIFigure, 'semicircular');
            app.PocoaptoGauge.Limits = [0 1];
            app.PocoaptoGauge.MajorTicks = [0 0.5 1];
            app.PocoaptoGauge.MajorTickLabels = {'0', '0.5', '1'};
            app.AptoGaugeLabel = uilabel(app.FISSelectionUIFigure);
            app.AptoGaugeLabel.Text = 'Apto';
            app.AptoGauge = uigauge(app.FISSelectionUIFigure, 'semicircular');
            app.AptoGauge.Limits = [0 1];
            app.AptoGauge.MajorTicks = [0 0.5 1];
            app.AptoGauge.MajorTickLabels = {'0', '0.5', '1'};
            app.NoaptoGaugeLabel = uilabel(app.FISSelectionUIFigure);
            app.NoaptoGaugeLabel.Text = 'No apto';
            app.NoaptoGauge = uigauge(app.FISSelectionUIFigure, 'semicircular');
            app.NoaptoGauge.Limits = [0 1];
            app.NoaptoGauge.MajorTicks = [0 0.5 1];
            app.NoaptoGauge.MajorTickLabels = {'0', '0.5', '1'};
            app.PocoaptoEditField = uieditfield(app.FISSelectionUIFigure, 'numeric');
            app.PocoaptoEditField.Editable = 'off';
            app.AptoEditField = uieditfield(app.FISSelectionUIFigure, 'numeric');
            app.AptoEditField.Editable = 'off';
            app.NoaptoEditField = uieditfield(app.FISSelectionUIFigure, 'numeric');
            app.NoaptoEditField.Editable = 'off';
            app.GradosdePertenenciaLabel = uilabel(app.FISSelectionUIFigure);
            app.GradosdePertenenciaLabel.Text = 'Grados de Pertenencia';
            app.TextArea = uitextarea(app.FISSelectionUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'El nivel de selección es una medida que varía entre 0 y 10 y que dice aproximadamente qué tan apta es una persona para un cargo, siendo 0 no apto y 10 apto.'};
            % Show the figure after all components are created
            app.FISSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FISSelectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FISSelectionUIFigure)
        end
    end
end